classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AlturamaximahdePEditField     matlab.ui.control.NumericEditField
        AlturamaximahdePLabel         matlab.ui.control.Label
        CalcularTrabajoButton         matlab.ui.control.Button
        CalcularCampoElectricoButton  matlab.ui.control.Button
        ladoaEditField                matlab.ui.control.NumericEditField
        ladoaEditFieldLabel           matlab.ui.control.Label
        q3EditField                   matlab.ui.control.NumericEditField
        q3EditFieldLabel              matlab.ui.control.Label
        q2EditField                   matlab.ui.control.NumericEditField
        q2EditFieldLabel              matlab.ui.control.Label
        q1EditField                   matlab.ui.control.NumericEditField
        q1EditFieldLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function E_total = calcularCampo(app, h)
            % Constante de Coulomb
            k_e = 9e9; 
            
            % Valores de las cargas
            q1 = app.q1EditField.Value;
            q2 = app.q2EditField.Value;
            q3 = app.q3EditField.Value;
            
            % Distancia a entre las cargas
            a = app.ladoaEditField.Value;
            
            % Coordenadas de P
            xP = a / 2;
            yP = h;
            
            % Posiciones de las cargas
            c1 = [0, 0];    % q1
            c2 = [a, 0];    % q2
            c3 = [a/2, sqrt(3)*a/2];  % q3
            
            % Distancias desde P hasta cada carga
            rP1 = sqrt((xP - c1(1))^2 + (yP - c1(2))^2);
            rP2 = sqrt((xP - c2(1))^2 + (yP - c2(2))^2);
            rP3 = sqrt((xP - c3(1))^2 + (yP - c3(2))^2);
            
            % Componentes del campo eléctrico de cada carga
            E1 = k_e * abs(q1) / rP1^2;
            E2 = k_e * abs(q2) / rP2^2;
            E3 = k_e * abs(q3) / rP3^2;
            
            % Campo total
            E_total = E1 + E2 + E3;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularCampoElectricoButton
        function CalcularCampoElectricoButtonPushed(app, event)
            % Definir el rango de alturas (h) donde queremos calcular el campo
            h_max = app.AlturamaximahdePEditField.Value;
            h_values = linspace(0, h_max, 10);
            
            % Preasignar el vector de campos eléctricos
            valoresCampo = linspace(0, 0, 10); %Llena el vector con 10 ceros
            
            % Iterar sobre cada valor de h, llamando a la función calcularCampo(h)
            for i = 1:length(h_values)
                valoresCampo(i) = calcularCampo(app, h_values(i));
            end
            
            % Graficar el campo eléctrico en función de la altura h
            plot(app.UIAxes, h_values, valoresCampo, "*r");
            title(app.UIAxes, 'Campo Eléctrico en P vs Altura');
            xlabel(app.UIAxes, 'Altura (h)');
            ylabel(app.UIAxes, 'Campo');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.ladoaEditFieldLabel = uilabel(app.UIFigure);
            app.ladoaEditFieldLabel.Text = 'lado a';
            app.ladoaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularCampoElectricoButton = uibutton(app.UIFigure, 'push');
            app.CalcularCampoElectricoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCampoElectricoButtonPushed, true);
            app.CalcularCampoElectricoButton.Text = 'Calcular Campo Electrico';
            app.CalcularTrabajoButton = uibutton(app.UIFigure, 'push');
            app.CalcularTrabajoButton.Text = 'Calcular Trabajo';
            app.AlturamaximahdePLabel = uilabel(app.UIFigure);
            app.AlturamaximahdePLabel.Text = {'Altura'; 'maxima'; 'h de P'};
            app.AlturamaximahdePEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end